classdef guihumidity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        RPMLabel     matlab.ui.control.Label
        Label_5      matlab.ui.control.Label
        EditField_2  matlab.ui.control.NumericEditField
        Label_3      matlab.ui.control.Label
        ClearButton  matlab.ui.control.Button
        StartButton  matlab.ui.control.Button
        Slider_2     matlab.ui.control.Slider
        Label_4      matlab.ui.control.Label
        EditField2   matlab.ui.control.NumericEditField
        EditField    matlab.ui.control.NumericEditField
        Slider       matlab.ui.control.Slider
        Label_2      matlab.ui.control.Label
        TextArea     matlab.ui.control.TextArea
        Label        matlab.ui.control.Label
        Image        matlab.ui.control.Image
    end

    
    properties (Access = private)
        humiditylevel = 0;
        templevel = 0;
        sys;
        currentlevel = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.sys = readfis('fuzzysystem.fis');
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.EditField.Value = app.Slider.Value;
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            app.EditField2.Value = app.Slider_2.Value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.humiditylevel = app.EditField2.Value;
            app.templevel = app.EditField.Value;


            if (app.templevel <= 14) || (app.humiditylevel <= 35)
                app.EditField_2.Value = 0;
            elseif (app.templevel > 0) && (app.templevel <= 35) && (app.humiditylevel > 35) && (app.humiditylevel <= 100)
                app.currentlevel = evalfis(app.sys, [app.humiditylevel app.templevel]);
                app.EditField_2.Value = app.currentlevel;
            end


            if (app.humiditylevel >= 35 && app.humiditylevel <= 65) && (app.templevel >= 14 && app.templevel <= 25)
                app.TextArea.Value = 'รอบพัดลมเครื่องดูดความชื้นทำงานสูง';

            elseif (app.humiditylevel >= 35 && app.humiditylevel <= 65) && (app.templevel >= 24 && app.templevel <= 45)
                app.TextArea.Value = 'รอบพัดลมเครื่องดูดความชื้นทำงานต่ำ';

            elseif (app.humiditylevel >= 64 && app.humiditylevel <= 100) && (app.templevel >= 14 && app.templevel <= 25)
                app.TextArea.Value = 'รอบพัดลมเครื่องดูดความชื้นทำงานสูง';

            elseif (app.humiditylevel >= 64 && app.humiditylevel <= 100) && (app.templevel >= 24 && app.templevel <= 45)
                app.TextArea.Value = 'รอบพัดลมเครื่องดูดความชื้นทำงานต่ำ';

            else
                app.TextArea.Value = 'Error';
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.TextArea.Value="";
            app.EditField.Value=0;
            app.EditField2.Value=0;
            app.EditField_2.Value=0;
            app.Slider.Value=15;
            app.Slider_2.Value=35;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'hum.jpg');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'สถานะ';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'อุณหภูมิ';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [15 35];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 15;
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Editable = 'off';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2.ValueDisplayFormat = '%.0f';
            app.EditField2.Editable = 'off';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'ความชื้นในดิน';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [35 100];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.Value = 35;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'ความเร็วรอบเครื่องดูดอากาศ';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'โปรแกรมควบคุมเครื่องดูดความชื้น';
            app.RPMLabel = uilabel(app.UIFigure);
            app.RPMLabel.Text = 'RPM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guihumidity

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end